classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        speckleSwitch               matlab.ui.control.Switch
        speckleSwitchLabel          matlab.ui.control.Label
        rotateimageSlider           matlab.ui.control.Slider
        rotateimageSliderLabel      matlab.ui.control.Label
        gaussianSwitch              matlab.ui.control.Switch
        gaussianSwitchLabel         matlab.ui.control.Label
        saltandpeperSwitch          matlab.ui.control.Switch
        saltandpeperSwitchLabel     matlab.ui.control.Label
        infoTextArea                matlab.ui.control.TextArea
        infoTextAreaLabel           matlab.ui.control.Label
        scoreEditField              matlab.ui.control.EditField
        scoreEditFieldLabel         matlab.ui.control.Label
        LabelEditField              matlab.ui.control.EditField
        LabelEditFieldLabel         matlab.ui.control.Label
        advancedoptionsSwitch       matlab.ui.control.ToggleSwitch
        advancedoptionsSwitchLabel  matlab.ui.control.Label
        cameraButton                matlab.ui.control.Button
        output                      matlab.ui.control.UIAxes
        input                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        %all variables are global acces via app.variable
        %gezondheid_textfiles
        cola;
        fanta;
        generalinfo;
        sanpellegrino;
        schweppes_mojito;
        schweppes_wildberries;
    end

    properties (Access = private)
        % Main app
        GUIapp;
        % Dialog box app
        DialogApp;
    end

    methods(Access = public)
        function getOutputImage(app,img)
               imshow(img,'Parent',app.output);
        end

        function getInputImage(app,img)
            imshow(img,'Parent',app.input);
        end
        
        function img = addNoise(app,img)
            if(app.saltandpeperSwitch.Value == "On")
                img = imnoise(img,'salt & pepper');
            end
            if(app.gaussianSwitch.Value == "On")
                img = imnoise(img,'gaussian');
            end
            if(app.speckleSwitch.Value == "On")
                img = imnoise(img,'speckle');
            end
            img = imrotate(img,app.rotateimageSlider.Value,'bilinear','crop'); 
        end

        function setScore(app,score)
            app.scoreEditField.Value = score;
        end

        function setLabel(app,label)
            app.LabelEditField.Value = label;
        end

        function setInfo(app,label)
                if(contains(label,"cola"))
                    app.infoTextArea.Value = app.cola{1};
                elseif(contains(label,"fanta"))
                    app.infoTextArea.Value = app.fanta{1};
                elseif(contains(label,"sanp"))
                    app.infoTextArea.Value = app.sanpellegrino{1};
                elseif(contains(label,"green"))
                    app.infoTextArea.Value = app.schweppes_mojito{1};
                elseif(contains(label,"purple"))
                    app.infoTextArea.Value = app.schweppes_wildberries{1};
                else
                    app.infoTextArea.Value = app.generalinfo{1};
                end
        end
    end

    methods (Access = private)
        function Loading(app)
            imshow('img/loading.gif','Parent',app.input);
            imshow('img/loading.gif','Parent',app.output);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %cola
             fileID = fopen("gezondheid_textfiles\coca_cola.txt");
             app.cola = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);
             %fanta
             fileID = fopen("gezondheid_textfiles\fanta.txt");
             app.fanta = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);
             %generalinfo
             fileID = fopen("gezondheid_textfiles\general.txt");
             app.generalinfo = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);
             %sanpellegrino
             fileID = fopen("gezondheid_textfiles\sanpellegrino.txt");
             app.sanpellegrino = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);
             %schweppes_mojito
             fileID = fopen("gezondheid_textfiles\schweppes_mojito.txt");
             app.schweppes_mojito = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);
             %chweppes_wildberries
             fileID = fopen("gezondheid_textfiles\schweppes_wildberries.txt");
             app.schweppes_wildberries = textscan(fileID, '%s', 'Delimiter', '\n');
             fclose(fileID);

             app.setInfo("");
        end

        % Button pushed function: cameraButton
        function cameraButtonPushed(app, event)
            app.Loading();
            app.DialogApp = miniGUI(app);
        end

        % Value changed function: advancedoptionsSwitch
        function advancedoptionsSwitchValueChanged(app, event)
            if(app.advancedoptionsSwitch.Value == "On")
                app.saltandpeperSwitch.Visible = "on";
                app.saltandpeperSwitchLabel.Visible = "on";

                app.speckleSwitch.Visible = "on";
                app.speckleSwitchLabel.Visible = "on";

                app.gaussianSwitch.Visible = "on";
                app.gaussianSwitchLabel.Visible = "on";

                app.rotateimageSlider.Visible = "on";
                app.rotateimageSliderLabel.Visible = "on";
            else
               app.saltandpeperSwitch.Visible = "off";
               app.saltandpeperSwitchLabel.Visible = "off";

               app.speckleSwitch.Visible = "off";
               app.speckleSwitchLabel.Visible = "off";

               app.gaussianSwitch.Visible = "off";
               app.gaussianSwitchLabel.Visible = "off";

               app.rotateimageSlider.Visible = "off";
               app.rotateimageSliderLabel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.input = uiaxes(app.UIFigure);
            title(app.input, 'input')
            app.input.XTick = [];
            app.input.YTick = [];
            app.output = uiaxes(app.UIFigure);
            title(app.output, 'output')
            app.output.XTick = [];
            app.output.YTick = [];
            app.cameraButton = uibutton(app.UIFigure, 'push');
            app.cameraButton.ButtonPushedFcn = createCallbackFcn(app, @cameraButtonPushed, true);
            app.cameraButton.Text = 'camera';
            app.advancedoptionsSwitchLabel = uilabel(app.UIFigure);
            app.advancedoptionsSwitchLabel.Text = 'advanced options';
            app.advancedoptionsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.advancedoptionsSwitch.ItemsData = {'Off', 'On'};
            app.advancedoptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @advancedoptionsSwitchValueChanged, true);
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.LabelEditField.Editable = 'off';
            app.scoreEditFieldLabel = uilabel(app.UIFigure);
            app.scoreEditFieldLabel.Text = 'score';
            app.scoreEditField = uieditfield(app.UIFigure, 'text');
            app.scoreEditField.Editable = 'off';
            app.infoTextAreaLabel = uilabel(app.UIFigure);
            app.infoTextAreaLabel.Text = 'info';
            app.infoTextArea = uitextarea(app.UIFigure);
            app.saltandpeperSwitchLabel = uilabel(app.UIFigure);
            app.saltandpeperSwitchLabel.Visible = 'off';
            app.saltandpeperSwitchLabel.Text = 'salt and peper';
            app.saltandpeperSwitch = uiswitch(app.UIFigure, 'slider');
            app.saltandpeperSwitch.ItemsData = {'Off', 'On'};
            app.saltandpeperSwitch.Visible = 'off';
            app.gaussianSwitchLabel = uilabel(app.UIFigure);
            app.gaussianSwitchLabel.Visible = 'off';
            app.gaussianSwitchLabel.Text = 'gaussian';
            app.gaussianSwitch = uiswitch(app.UIFigure, 'slider');
            app.gaussianSwitch.ItemsData = {'Off', 'On'};
            app.gaussianSwitch.Visible = 'off';
            app.rotateimageSliderLabel = uilabel(app.UIFigure);
            app.rotateimageSliderLabel.Visible = 'off';
            app.rotateimageSliderLabel.Text = 'rotate image';
            app.rotateimageSlider = uislider(app.UIFigure);
            app.rotateimageSlider.Limits = [-180 180];
            app.rotateimageSlider.Visible = 'off';
            app.speckleSwitchLabel = uilabel(app.UIFigure);
            app.speckleSwitchLabel.Visible = 'off';
            app.speckleSwitchLabel.Text = 'speckle';
            app.speckleSwitch = uiswitch(app.UIFigure, 'slider');
            app.speckleSwitch.ItemsData = {'Off', 'On'};
            app.speckleSwitch.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end