classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        resnetButton  matlab.ui.control.Button
        YOLOButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp; % Main app
        inputImg;
        camera = webcam(1);
        YOLOnet;
        resnet;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            %YOLO network
            YOLOload = load('detectorYolo2LowLR.mat');
            app.YOLOnet = YOLOload.detectorYolo2;

            %resload = load('resNet_trained_1.mat');
            %app.resnet = resload;
        end

        % Button pushed function: YOLOButton
        function YOLOButtonPushed(app, event)
              h = figure;

              while ishandle(h)
                    frame = snapshot(app.camera);
                    getInputImage(app.CallingApp,frame);
                    [allBboxes,allScore,allLabels] = detect(app.YOLOnet,frame,'Threshold',0.6,'SelectStrongest',true);
                    [bboxes,scores,labels] = selectStrongestBboxMulticlass(allBboxes,allScore,allLabels,'OverlapThreshold',0.1);
                    im = insertObjectAnnotation(frame,'rectangle',bboxes,cellstr(labels),Fontsize=32);
                    getOutputImage(app.CallingApp,im);
                    image(im);
                    if (isempty(labels) == false)
                        labelstring = string(labels);
                        setLabel(app.CallingApp,char(labelstring(1,1)));
                        setScore(app.CallingApp,num2str(max(scores),2));
                    end
                    title({char(labels), num2str(max(scores),2)});
                    drawnow
              end
        end

        % Button pushed function: resnetButton
        function resnetButtonPushed(app, event)
              load('trainedNetwork_ResNet6__.mat');
              net = trainedNetwork_ResNet6;
             
              %227 227
              inputSize = net.Layers(1).InputSize(1:2);
              
              h = figure;
              %sendInfo(app.CallingApp,"button ingedrukt")
              while ishandle(h)
                    im = snapshot(app.camera);
                    getInputImage(app.CallingApp,im);
                    image(im);
                    im = imresize(im,inputSize);
                    [label,score] = classify(net,im);
                    getOutputImage(app.CallingApp,im);
                    setLabel(app.CallingApp,char(label));
                    setScore(app.CallingApp,num2str(max(score),2));
                    title({char(label), num2str(max(score),2)});
                    drawnow
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YOLOButton = uibutton(app.UIFigure, 'push');
            app.YOLOButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOButtonPushed, true);
            app.YOLOButton.Text = 'YOLO';
            app.resnetButton = uibutton(app.UIFigure, 'push');
            app.resnetButton.ButtonPushedFcn = createCallbackFcn(app, @resnetButtonPushed, true);
            app.resnetButton.Text = 'resnet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end