classdef miniGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        resnetButton  matlab.ui.control.Button
        YOLOButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp; % Main app
        inputImg;
        camera = webcam(1);
        YOLOnet;
        resnet;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            %YOLO model
            yolomodel = load('models/detectorYolo2LowLR.mat');
            app.YOLOnet = yolomodel.detectorYolo2;
            %resnet model
            resnetmodel = load('models/trainedNetwork_ResNet6__.mat');
            app.resnet = resnetmodel.trainedNetwork_ResNet6;
        end

        % Button pushed function: YOLOButton
        function YOLOButtonPushed(app, event)
              h = figure;

              while ishandle(h)
                    frame = snapshot(app.camera);
                    getInputImage(app.CallingApp,frame);
                    frame = addNoise(app.CallingApp,frame);
                    [allBboxes,allScore,allLabels] = detect(app.YOLOnet,frame,'Threshold',0.6,'SelectStrongest',true);
                    [bboxes,scores,labels] = selectStrongestBboxMulticlass(allBboxes,allScore,allLabels,'OverlapThreshold',0.1);
                    im = insertObjectAnnotation(frame,'rectangle',bboxes,cellstr(labels),Fontsize=32);
                    getOutputImage(app.CallingApp,im);
                    image(im);
                    if (isempty(labels) == false)
                        labelstring = string(labels);
                        setLabel(app.CallingApp,char(labelstring(1,1)));
                        setScore(app.CallingApp,num2str(max(scores),2));
                        setInfo(app.CallingApp,char(labelstring(1,1)));
                    end
                    title({char(labels), num2str(max(scores),2)});
                    drawnow
              end
        end

        % Button pushed function: resnetButton
        function resnetButtonPushed(app, event)
              %227 227
              inputSize = app.resnet.Layers(1).InputSize(1:2);
              h = figure;
          
              while ishandle(h)
                    im = snapshot(app.camera);
                    getInputImage(app.CallingApp,im);
                    im = imresize(im,inputSize);
                    im = addNoise(app.CallingApp,im);
                    image(im);
                    [label,score] = classify(app.resnet,im);
                    getOutputImage(app.CallingApp,im);
                    setLabel(app.CallingApp,char(label));
                    setScore(app.CallingApp,num2str(max(score),2));
                    setInfo(app.CallingApp,string(label));
                    title({char(label), num2str(max(score),2)});
                    drawnow
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YOLOButton = uibutton(app.UIFigure, 'push');
            app.YOLOButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOButtonPushed, true);
            app.YOLOButton.Text = 'YOLO';
            app.resnetButton = uibutton(app.UIFigure, 'push');
            app.resnetButton.ButtonPushedFcn = createCallbackFcn(app, @resnetButtonPushed, true);
            app.resnetButton.Text = 'resnet';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end